classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        CommunityTab               matlab.ui.container.Tab
        Image2                     matlab.ui.control.Image
        PostButton                 matlab.ui.control.Button
        DonationAmountEditField    matlab.ui.control.EditField
        DonationAmountLabel        matlab.ui.control.Label
        DonateButton               matlab.ui.control.Button
        ReplyEditField             matlab.ui.control.EditField
        ReplyEditFieldLabel        matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        SelectapostListBox         matlab.ui.control.ListBox
        SelectapostListBoxLabel    matlab.ui.control.Label
        ViewingTextArea            matlab.ui.control.TextArea
        ViewingTextAreaLabel       matlab.ui.control.Label
        YourPostsTab               matlab.ui.container.Tab
        MoneyRaisedEditField       matlab.ui.control.NumericEditField
        MoneyRaisedLabel           matlab.ui.control.Label
        PostButton_2               matlab.ui.control.Button
        CreatePostTextArea         matlab.ui.control.TextArea
        CreatePostLabel            matlab.ui.control.Label
        SelectapostListBox_2       matlab.ui.control.ListBox
        SelectapostListBox_2Label  matlab.ui.control.Label
        ViewingTextArea_2          matlab.ui.control.TextArea
        ViewingTextArea_2Label     matlab.ui.control.Label
        Image_2                    matlab.ui.control.Image
        GenerateIdeaButton         matlab.ui.control.Button
        IdeaEditField              matlab.ui.control.EditField
        IdeaEditFieldLabel         matlab.ui.control.Label
        TrendingTab                matlab.ui.container.Tab
        Image3                     matlab.ui.control.Image
        DonationAmountEditField_2  matlab.ui.control.EditField
        DonationAmountLabel_2      matlab.ui.control.Label
        DonateButton_2             matlab.ui.control.Button
        SelectapostListBox_3       matlab.ui.control.ListBox
        SelectapostListBox_3Label  matlab.ui.control.Label
        ViewingTextArea_3          matlab.ui.control.TextArea
        ViewingTextArea_3Label     matlab.ui.control.Label
        Image_3                    matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateIdeaButton
        function GenerateIdeaButtonPushed(app, event)

            t = randi(10);
            if t == 1
                str = "Start a community garden";
            elseif t==2
                str = "Plant a tree";
            elseif t==3
                str = "Pick up trash in a park";
            elseif t==4
                str = "Hand out reusable or paper bags in a store";
            elseif t==5
                str = "Start a carpool/bike group";
            elseif t==6
                str = "Promote public transportation";
            elseif t==7   
                str = "Raise money for animal conservation";
            elseif t==8  
                str = "Organize a community swap";
            elseif t==9    
                str = "Adopt a grassless lawn";
            elseif t==10
                str = "Encourage zero waste practices at work";
            end

            app.IdeaEditField.Value = str;
        end

        % Value changed function: IdeaEditField
        function IdeaEditFieldValueChanged(app, event)
            
        end

        % Clicked callback: SelectapostListBox
        function SelectapostListBoxClicked(app, event)
            switch app.SelectapostListBox.Value
                case 'Picking up trash in Mackenzie Main City Park'
                    str1 = "Hi everyone! My name is Samantha, and I, like (I’m sure) you, love walking in Mackenzie City Park. My dogs love it too. However, the litter problem there is beginning to get out of hand, which is why I’m planning to organize a clean-up this weekend, from... ";
                    app.Image.ImageSource = "mack.jpg";
                    app.Image2.ImageSource = "p1.jpg";
                case 'Bags in United Supermarkets'
                    str1 = "Hey y'all! I'm starting a project to replace all plastic bags in our United Supermarkets with paper or reusable bags. Please join me if you can, donate any old bags, and donate if you can to help us buy more bags! We will be...";
                    app.Image.ImageSource = "mark.jpg";
                    app.Image2.ImageSource = "p2.png";
            end
            app.ViewingTextArea.Value=str1;
        end

        % Button pushed function: DonateButton
        function DonateButtonPushed(app, event)
            app.DonationAmountEditField.Value = "Submitted";
        end

        % Button pushed function: PostButton
        function PostButtonPushed(app, event)
            app.ReplyEditField.Value = "Submitted";
        end

        % Button pushed function: PostButton_2
        function PostButton_2Pushed(app, event)
            app.CreatePostTextArea.Value = "Submitted";
        end

        % Clicked callback: SelectapostListBox_2
        function SelectapostListBox_2Clicked(app, event)
            switch app.SelectapostListBox_2.Value
                case 'Community Garden'
                    str2 = "Hi guys! I'm starting a community garden in our neighborhood; please let me know if you're interested in joining! Bring any crops you have, or donate, and we will be getting...";
                    app.Image_2.ImageSource = "nei.jpg";
                case 'Public Transport'
                    str2 = "Hey everyone! As more and more people move to our city and our city's population grows, it becomes more and more important for us to have a good public transportation system. We currently...";
                    app.Image_2.ImageSource = "met.jpg";
            end
            app.ViewingTextArea_2.Value = str2;
        end

        % Clicked callback: SelectapostListBox_3
        function SelectapostListBox_3Clicked(app, event)
            switch app.SelectapostListBox_3.Value
                case 'Cleaning up Galveston Beach'
                    str3 = "Hey guys! Our beach has really been mistreated over the past few months, and it's time we show it some love. We're hosting a cleanup on...";
                    app.Image_3.ImageSource = "bea.png";
                    app.Image3.ImageSource = "p3.png";
                case 'Pangolin Preservation'
                    str3 = "Hello, this post is going to be a heavy one. Pangolins are an endangered species, and over the past decade, they have experienced a significant decline in population. Please donate to the conservation organization...";
                    app.Image_3.ImageSource = "end.jpg";
                    app.Image3.ImageSource = "p1.jpg";
            end
            app.ViewingTextArea_3.Value=str3;
        end

        % Button pushed function: DonateButton_2
        function DonateButton_2Pushed(app, event)
            app.DonationAmountEditField_2.Value = "Submitted";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CommunityTab = uitab(app.TabGroup);
            app.CommunityTab.Title = 'Community';
            app.ViewingTextAreaLabel = uilabel(app.CommunityTab);
            app.ViewingTextAreaLabel.Text = 'Viewing:';
            app.ViewingTextArea = uitextarea(app.CommunityTab);
            app.SelectapostListBoxLabel = uilabel(app.CommunityTab);
            app.SelectapostListBoxLabel.Text = 'Select a post:';
            app.SelectapostListBox = uilistbox(app.CommunityTab);
            app.SelectapostListBox.Items = {'Picking up trash in Mackenzie Main City Park', 'Bags in United Supermarkets'};
            app.SelectapostListBox.ClickedFcn = createCallbackFcn(app, @SelectapostListBoxClicked, true);
            app.SelectapostListBox.Value = 'Picking up trash in Mackenzie Main City Park';
            app.Image = uiimage(app.CommunityTab);
            app.ReplyEditFieldLabel = uilabel(app.CommunityTab);
            app.ReplyEditFieldLabel.Text = 'Reply:';
            app.ReplyEditField = uieditfield(app.CommunityTab, 'text');
            app.DonateButton = uibutton(app.CommunityTab, 'push');
            app.DonateButton.ButtonPushedFcn = createCallbackFcn(app, @DonateButtonPushed, true);
            app.DonateButton.Text = 'Donate';
            app.DonationAmountLabel = uilabel(app.CommunityTab);
            app.DonationAmountLabel.Text = 'Donation Amount: $';
            app.DonationAmountEditField = uieditfield(app.CommunityTab, 'text');
            app.PostButton = uibutton(app.CommunityTab, 'push');
            app.PostButton.ButtonPushedFcn = createCallbackFcn(app, @PostButtonPushed, true);
            app.PostButton.Text = 'Post';
            app.Image2 = uiimage(app.CommunityTab);
            app.YourPostsTab = uitab(app.TabGroup);
            app.YourPostsTab.Title = 'Your Posts';
            app.IdeaEditFieldLabel = uilabel(app.YourPostsTab);
            app.IdeaEditFieldLabel.Text = 'Idea:';
            app.IdeaEditField = uieditfield(app.YourPostsTab, 'text');
            app.IdeaEditField.ValueChangedFcn = createCallbackFcn(app, @IdeaEditFieldValueChanged, true);
            app.GenerateIdeaButton = uibutton(app.YourPostsTab, 'push');
            app.GenerateIdeaButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateIdeaButtonPushed, true);
            app.GenerateIdeaButton.Text = 'Generate Idea';
            app.Image_2 = uiimage(app.YourPostsTab);
            app.ViewingTextArea_2Label = uilabel(app.YourPostsTab);
            app.ViewingTextArea_2Label.Text = 'Viewing:';
            app.ViewingTextArea_2 = uitextarea(app.YourPostsTab);
            app.SelectapostListBox_2Label = uilabel(app.YourPostsTab);
            app.SelectapostListBox_2Label.Text = 'Select a post:';
            app.SelectapostListBox_2 = uilistbox(app.YourPostsTab);
            app.SelectapostListBox_2.Items = {'Community Garden', 'Public Transport'};
            app.SelectapostListBox_2.ClickedFcn = createCallbackFcn(app, @SelectapostListBox_2Clicked, true);
            app.SelectapostListBox_2.Value = 'Community Garden';
            app.CreatePostLabel = uilabel(app.YourPostsTab);
            app.CreatePostLabel.Text = 'Create Post:';
            app.CreatePostTextArea = uitextarea(app.YourPostsTab);
            app.PostButton_2 = uibutton(app.YourPostsTab, 'push');
            app.PostButton_2.ButtonPushedFcn = createCallbackFcn(app, @PostButton_2Pushed, true);
            app.PostButton_2.Text = 'Post';
            app.MoneyRaisedLabel = uilabel(app.YourPostsTab);
            app.MoneyRaisedLabel.Text = 'Money Raised: $';
            app.MoneyRaisedEditField = uieditfield(app.YourPostsTab, 'numeric');
            app.TrendingTab = uitab(app.TabGroup);
            app.TrendingTab.Title = 'Trending';
            app.Image_3 = uiimage(app.TrendingTab);
            app.ViewingTextArea_3Label = uilabel(app.TrendingTab);
            app.ViewingTextArea_3Label.Text = 'Viewing:';
            app.ViewingTextArea_3 = uitextarea(app.TrendingTab);
            app.SelectapostListBox_3Label = uilabel(app.TrendingTab);
            app.SelectapostListBox_3Label.Text = 'Select a post:';
            app.SelectapostListBox_3 = uilistbox(app.TrendingTab);
            app.SelectapostListBox_3.Items = {'Cleaning up Galveston Beach', 'Pangolin Preservation'};
            app.SelectapostListBox_3.ClickedFcn = createCallbackFcn(app, @SelectapostListBox_3Clicked, true);
            app.SelectapostListBox_3.Value = 'Cleaning up Galveston Beach';
            app.DonateButton_2 = uibutton(app.TrendingTab, 'push');
            app.DonateButton_2.ButtonPushedFcn = createCallbackFcn(app, @DonateButton_2Pushed, true);
            app.DonateButton_2.Text = 'Donate';
            app.DonationAmountLabel_2 = uilabel(app.TrendingTab);
            app.DonationAmountLabel_2.Text = 'Donation Amount: $';
            app.DonationAmountEditField_2 = uieditfield(app.TrendingTab, 'text');
            app.Image3 = uiimage(app.TrendingTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end